classdef app1_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        removetaskButton        matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        ListBoxLabel            matlab.ui.control.Label
        removecodeButton        matlab.ui.control.Button
        savetaskButton          matlab.ui.control.Button
        taskDropDown            matlab.ui.control.DropDown
        taskDropDownLabel       matlab.ui.control.Label
        sendcodeEditField       matlab.ui.control.EditField
        sendcodeEditFieldLabel  matlab.ui.control.Label
        sendButton              matlab.ui.control.Button
        saveButton              matlab.ui.control.Button
        codeDropDown            matlab.ui.control.DropDown
        codeDropDownLabel       matlab.ui.control.Label
        codeEditField           matlab.ui.control.EditField
        codeEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        code = string([]) % Array of codes
        code_dict = dictionary(string([]),string([]))
        code_listbox = string([])
    end
    
    methods (Access = private)

        function Dict = setTask(app,Dict,code,task)
            Dict(code) = task;
            
        end 
        
        function Dict = removeTask(app,Dict,code)
            Dict(code) = [];
            
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: sendButton
        function sendButtonPushed(app, event)
            app.codeEditField.Value = app.sendcodeEditField.Value;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            if ~ismember(app.codeEditField.Value,app.codeDropDown.Items)
                app.code(end+1) = app.codeEditField.Value;
                app.codeDropDown.Items = app.code;
            end
        end

        % Button pushed function: removecodeButton
        function removecodeButtonPushed(app, event)
            app.code(app.code == app.codeDropDown.Value) = [];
            app.codeDropDown.Items = app.code;
        end

        % Button pushed function: savetaskButton
        function savetaskButtonPushed(app, event)

            if numel(app.code_listbox) > 0
                code_listbox_split = split(app.code_listbox," = ");
                code_listbox_split = code_listbox_split(:,:,1);
                [is_found,idx] = ismember(app.codeDropDown.Value,code_listbox_split);
            else
                is_found = false;
            end

            if is_found
                app.code_listbox(idx) = app.codeDropDown.Value + " = " + app.taskDropDown.Value;
            else
                app.code_listbox(end+1) = app.codeDropDown.Value + " = " + app.taskDropDown.Value;
            end
            app.ListBox.Items = app.code_listbox;
            app.code_dict = setTask(app,app.code_dict,app.codeDropDown.Value,app.taskDropDown.Value);
        end

        % Button pushed function: removetaskButton
        function removetaskButtonPushed(app, event)
            selected_listbox_item = split(app.ListBox.Value, " = ");
            key = selected_listbox_item(1);
            key = string(key);
            app.code_dict = removeTask(app,app.code_dict,key);

            [~,idx] = ismember(app.ListBox.Value,app.code_listbox);
            app.code_listbox(idx) = [];
            app.ListBox.Items = app.code_listbox;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.codeEditFieldLabel = uilabel(app.UIFigure);
            app.codeEditFieldLabel.Text = 'code';
            app.codeEditField = uieditfield(app.UIFigure, 'text');
            app.codeDropDownLabel = uilabel(app.UIFigure);
            app.codeDropDownLabel.Text = 'code';
            app.codeDropDown = uidropdown(app.UIFigure);
            app.codeDropDown.Items = {};
            app.codeDropDown.Value = {};
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.sendButton = uibutton(app.UIFigure, 'push');
            app.sendButton.ButtonPushedFcn = createCallbackFcn(app, @sendButtonPushed, true);
            app.sendButton.Text = 'send';
            app.sendcodeEditFieldLabel = uilabel(app.UIFigure);
            app.sendcodeEditFieldLabel.Text = 'send code';
            app.sendcodeEditField = uieditfield(app.UIFigure, 'text');
            app.taskDropDownLabel = uilabel(app.UIFigure);
            app.taskDropDownLabel.Text = 'task';
            app.taskDropDown = uidropdown(app.UIFigure);
            app.taskDropDown.Items = {'ON', 'OFF', 'TOGGLE'};
            app.taskDropDown.Value = 'ON';
            app.savetaskButton = uibutton(app.UIFigure, 'push');
            app.savetaskButton.ButtonPushedFcn = createCallbackFcn(app, @savetaskButtonPushed, true);
            app.savetaskButton.Text = 'save task';
            app.removecodeButton = uibutton(app.UIFigure, 'push');
            app.removecodeButton.ButtonPushedFcn = createCallbackFcn(app, @removecodeButtonPushed, true);
            app.removecodeButton.Text = ' remove code';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.removetaskButton = uibutton(app.UIFigure, 'push');
            app.removetaskButton.ButtonPushedFcn = createCallbackFcn(app, @removetaskButtonPushed, true);
            app.removetaskButton.Text = 'remove task';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end